classdef J3DEDS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MagnitudeofDegreesEditFieldLabel  matlab.ui.control.Label
        MagnitudeofDegreesEditField    matlab.ui.control.NumericEditField
        StartExperimentButton          matlab.ui.control.StateButton
        TimeEditFieldLabel             matlab.ui.control.Label
        TimeEditField                  matlab.ui.control.NumericEditField
        ResetButton                    matlab.ui.control.StateButton
        msLabel                        matlab.ui.control.Label
        DegreesLabel                   matlab.ui.control.Label
        CalibrateButton                matlab.ui.control.StateButton
        LeftButton                     matlab.ui.control.StateButton
        RightButton                    matlab.ui.control.StateButton
        CounterClockwiseButton         matlab.ui.control.StateButton
        ClockwiseButton                matlab.ui.control.StateButton
        ChooseEyeForRotationLabel      matlab.ui.control.Label
        ChooseDirectionForRotationLabel  matlab.ui.control.Label
        GaugeLabel                     matlab.ui.control.Label
        Gauge                          matlab.ui.control.SemicircularGauge
        EnterValuesbetween0and60Label  matlab.ui.control.Label
        EnterPositiveValuesLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        a = arduino() % Description
        
        update
        Left
        CounterClockwise
    end
    
    methods (Access = private)
        
        function updatePos(app)
            s = servo(app.a,'D3');
            s2 = servo(app.a,'D4');
            Time = app.TimeEditField.Value;
            eye_deg = app.MagnitudeofDegreesEditField.Value;
            
            %eye to servo rotation ratio


            servo_radi = 22; %22mm last hole, 20mm second to last hole, ...

            eye_radi = 6; %6mm is the radius about which the eye rotates

%             ratio = eye_radi/servo_radi; %inverse ratio to solve backwards for servo degrees rotated
% 
%             Deg = eye_deg*ratio;   
%             
            %new equation
            Servo_Deg = asind(eye_radi*tand(eye_deg)/servo_radi); %Verify eye rotates exact selected amount


            Pos = readPosition(s);
            Pos2 = readPosition(s2);
            
            T = Time/1000;
                                                      

            if  app.CounterClockwise == 1 
                Mag = 0.5-(Servo_Deg/180);
                                                      
                Step = -1/1000;                         
                %how to make steps more accurate without making experiment
                %take longer than specified?
                Inter = T/((Mag-Pos)/Step);
                Inter2 = T/((Mag-Pos2)/Step);
            else
                Mag = 0.5+(Servo_Deg/180);
                                                        
                Step = 1/1000;
                Inter = T/((Mag-Pos)/Step);
                Inter2 = T/((Mag-Pos2)/Step);
                
            end
            
            if app.Left == 0
                
                if app.update == 1 %and Pos < Mag 
                
                
                    for i = Pos2:Step:Mag
                        writePosition(s2,i);
                        G = atand(servo_radi*sind((i-0.5)*180)/eye_radi);          
                        app.Gauge.Value = G; 
                        pause(Inter2);            %verify that it runs for the exact amount of time 
                    end
                
                    app.update = 0;
            
                end
            
            else 
                
                if app.update == 1
                    for i = Pos:Step:Mag
                        writePosition(s,i);
                        G = atand(servo_radi*sind((i-0.5)*180)/eye_radi);    %G = (i-0.5)*180/ratio;
                        app.Gauge.Value = G;
                        pause(Inter);
                    end
                    app.update = 0;
                end
            
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear;
            
            app.a = arduino('COM5','Uno','Libraries','Servo');
            app.s = servo(app.a, 'D3');
            app.s2 = servo(app.a, 'D4');
            app.update = 0;
            
        end

        % Value changed function: StartExperimentButton
        function StartExperimentButtonValueChanged(app, event)
            app.update = 1;
            updatePos(app);
            app.StartExperimentButton.Value = 0;
        end

        % Value changed function: ResetButton
        function ResetButtonValueChanged(app, event)
            s = servo(app.a,'D3');
            s2 = servo(app.a,'D4');
            writePosition(s,0.5);
            writePosition(s2,0.5);
            app.Gauge.Value = 0;
            pause(2);
            app.ResetButton.Value = 0;
            
        end

        % Value changed function: LeftButton
        function LeftButtonValueChanged(app, event)
            app.Left = 1;

            app.RightButton.Value = 0;
            
        end

        % Value changed function: RightButton
        function RightButtonValueChanged(app, event)

            app.Left = 0;

            
            app.LeftButton.Value = 0;

        end

        % Value changed function: CounterClockwiseButton
        function CounterClockwiseButtonValueChanged(app, event)
            app.CounterClockwise = 1;
            
            app.ClockwiseButton.Value = 0;
            
        end

        % Value changed function: ClockwiseButton
        function ClockwiseButtonValueChanged(app, event)
            app.CounterClockwise = 0;
            
            app.CounterClockwiseButton.Value = 0;
            
        end

        % Value changed function: MagnitudeofDegreesEditField
        function MagnitudeofDegreesEditFieldValueChanged(app, event)
            eye_deg = app.MagnitudeofDegreesEditField.Value;
            if eye_deg > 60 || eye_deg < 0
                app.MagnitudeofDegreesEditField.Value = 0;
            end
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            Time = app.TimeEditField.Value;
            if Time < 0 
                app.TimeEditField.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MagnitudeofDegreesEditFieldLabel = uilabel(app.UIFigure);
            app.MagnitudeofDegreesEditFieldLabel.Text = 'Magnitude of Degrees';
            app.MagnitudeofDegreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.MagnitudeofDegreesEditField.ValueChangedFcn = createCallbackFcn(app, @MagnitudeofDegreesEditFieldValueChanged, true);
            app.StartExperimentButton = uibutton(app.UIFigure, 'state');
            app.StartExperimentButton.ValueChangedFcn = createCallbackFcn(app, @StartExperimentButtonValueChanged, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.ResetButton = uibutton(app.UIFigure, 'state');
            app.ResetButton.ValueChangedFcn = createCallbackFcn(app, @ResetButtonValueChanged, true);
            app.ResetButton.Text = 'Reset';
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = 'ms';
            app.DegreesLabel = uilabel(app.UIFigure);
            app.DegreesLabel.Text = 'Degrees';
            app.CalibrateButton = uibutton(app.UIFigure, 'state');
            app.CalibrateButton.Text = 'Calibrate';
            app.LeftButton = uibutton(app.UIFigure, 'state');
            app.LeftButton.ValueChangedFcn = createCallbackFcn(app, @LeftButtonValueChanged, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'state');
            app.RightButton.ValueChangedFcn = createCallbackFcn(app, @RightButtonValueChanged, true);
            app.RightButton.Text = 'Right';
            app.CounterClockwiseButton = uibutton(app.UIFigure, 'state');
            app.CounterClockwiseButton.ValueChangedFcn = createCallbackFcn(app, @CounterClockwiseButtonValueChanged, true);
            app.CounterClockwiseButton.Text = 'CounterClockwise';
            app.ClockwiseButton = uibutton(app.UIFigure, 'state');
            app.ClockwiseButton.ValueChangedFcn = createCallbackFcn(app, @ClockwiseButtonValueChanged, true);
            app.ClockwiseButton.Text = 'Clockwise';
            app.ChooseEyeForRotationLabel = uilabel(app.UIFigure);
            app.ChooseEyeForRotationLabel.Text = 'Choose Eye For Rotation';
            app.ChooseDirectionForRotationLabel = uilabel(app.UIFigure);
            app.ChooseDirectionForRotationLabel.Text = 'Choose Direction For Rotation';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            app.Gauge.Limits = [-60 60];
            app.EnterValuesbetween0and60Label = uilabel(app.UIFigure);
            app.EnterValuesbetween0and60Label.Text = 'Enter Values between 0 and 60';
            app.EnterPositiveValuesLabel = uilabel(app.UIFigure);
            app.EnterPositiveValuesLabel.Text = 'Enter Positive Values';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = J3DEDS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end